classdef GeoImage_Test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        Menu2                   matlab.ui.container.Menu
        Toolbar                 matlab.ui.container.Toolbar
        PushTool                matlab.ui.container.toolbar.PushTool
        PushTool2               matlab.ui.container.toolbar.PushTool
        EditField               matlab.ui.control.NumericEditField
        EditFieldLabel          matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        Spinner_month           matlab.ui.control.Spinner
        Spinner_date            matlab.ui.control.Spinner
        Spinner_year            matlab.ui.control.Spinner
        EnterCMDEditField       matlab.ui.control.EditField
        EnterCMDEditFieldLabel  matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
    end

    
     properties (Access = public)
        cellArrayText %  字符向量元胞数组
    end
    
    methods (Access = public)

        function GeoDispLogInfo(app, str)
            string = sprintf('~> %s\n', str); % 将数据格式化为字符串或字符向量
            app.cellArrayText = horzcat(app.cellArrayText, string); % 水平串联数组
            app.TextArea.Value = app.cellArrayText; % 给TextArea赋值
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cellArrayText = sprintf('~> %s\n', '***** log *****'); % 赋初值
            app.TextArea.Value=app.cellArrayText; % 文本区域中的初始显示信息
        end

        % Value changed function: EnterCMDEditField
        function EnterCMDEditFieldValueChanged(app, event)
            value = app.EnterCMDEditField.Value;
            disp(value);
            if (value ~= "")
                GeoDispLogInfo(app, value);
                app.EnterCMDEditField.Value = "";
                if (value == "clc")
                    app.EnterCMDEditField.Value = "";
                    app.cellArrayText = sprintf('~> %s\n', '***** log *****');
                    app.TextArea.Value = app.cellArrayText;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool2 = uipushtool(app.Toolbar);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.EnterCMDEditFieldLabel = uilabel(app.UIFigure);
            app.EnterCMDEditFieldLabel.Text = 'Enter CMD';
            app.EnterCMDEditField = uieditfield(app.UIFigure, 'text');
            app.EnterCMDEditField.ValueChangedFcn = createCallbackFcn(app, @EnterCMDEditFieldValueChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            app.Spinner_year = uispinner(app.GridLayout);
            app.Spinner_year.Limits = [1900 Inf];
            app.Spinner_year.Value = 1900;
            app.Spinner_date = uispinner(app.GridLayout);
            app.Spinner_date.Limits = [1 Inf];
            app.Spinner_date.Value = 1;
            app.Spinner_month = uispinner(app.GridLayout);
            app.Spinner_month.Limits = [1 12];
            app.Spinner_month.Value = 1;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeoImage_Test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end