classdef TextArea_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        EnterEditField       matlab.ui.control.EditField
        EnterEditFieldLabel  matlab.ui.control.Label
        TextAreaAppLabel     matlab.ui.control.Label
        TextArea             matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        cellArrayText %  字符向量元胞数组
    end
    
    methods (Access = public)

        function GeoDispLogInfo(app, str)
            string = sprintf('~> %s\n', str); % 将数据格式化为字符串或字符向量
            app.cellArrayText = horzcat(app.cellArrayText, string); % 水平串联数组
            app.TextArea.Value = app.cellArrayText; % 给TextArea赋值
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cellArrayText{1} = sprintf('~> %s\n', '***** log *****'); % 赋初值
            app.TextArea.Value=app.cellArrayText{1}; % 文本区域中的初始显示信息
        end

        % Value changed function: EnterEditField
        function EnterEditFieldValueChanged(app, event)
            value = app.EnterEditField.Value;
            if (value ~= "")
                GeoDispLogInfo(app, value);
                app.EnterEditField.Value = "";
                if (value == "clc")
                    app.EnterEditField.Value = "";
                    app.TextArea.Value = "";
                    app.cellArrayText = "";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextAreaAppLabel = uilabel(app.UIFigure);
            app.TextAreaAppLabel.Text = 'TextArea App';
            app.EnterEditFieldLabel = uilabel(app.UIFigure);
            app.EnterEditFieldLabel.Text = 'Enter:';
            app.EnterEditField = uieditfield(app.UIFigure, 'text');
            app.EnterEditField.ValueChangedFcn = createCallbackFcn(app, @EnterEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TextArea_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end